classdef Heart_Rate_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        TimeTextArea           matlab.ui.control.TextArea
        TimeLabel              matlab.ui.control.Label
        PeaksTextArea          matlab.ui.control.TextArea
        PeaksLabel             matlab.ui.control.Label
        SNRTextArea            matlab.ui.control.TextArea
        SNRLabel               matlab.ui.control.Label
        HRTextArea             matlab.ui.control.TextArea
        HRLabel                matlab.ui.control.Label
        SelectListBox          matlab.ui.control.ListBox
        SelectListBoxLabel     matlab.ui.control.Label
        StatusTextArea_2       matlab.ui.control.TextArea
        StatusTextArea_2Label  matlab.ui.control.Label
        CompareButton          matlab.ui.control.Button
        ProcessButton          matlab.ui.control.Button
        ImportButton           matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         datasetFiles = {};           % List of file names
         currentSignal = [];          % Raw PPG signal
         currentProcessed = [];       % Processed PPG signal
         currentPeaks = [];           % Detected peaks indices
         currentFS = 32;              % Sampling rate (default 32 Hz)
         currentHR = NaN;             % Heart rate (BPM)
         currentSNR = NaN;            % SNR improvement (dB)
         currentTime = NaN;           % Processing time (ms)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UpdatePlots(app)
            if isempty(app.currentSignal)
                return;
            end

            cla(app.UIAxes);
            t = 1:length(app.currentSignal);

            plot(app.UIAxes, t, app.currentSignal, 'b-', 'LineWidth', 0.7);
            hold(app.UIAxes, 'on');

            if ~isempty(app.currentProcessed)
                plot(app.UIAxes, t, app.currentProcessed, 'r-', 'LineWidth', 1.2);
            end

            if ~isempty(app.currentPeaks)
                plot(app.UIAxes, app.currentPeaks, app.currentProcessed(app.currentPeaks), ...
                    'go', 'MarkerSize', 6, 'MarkerFaceColor', 'g');
            end

            hold(app.UIAxes, 'off');

            if ~isnan(app.currentHR)
                title(app.UIAxes, sprintf('PPG signal | Heart Rate = %.1f BPM', app.currentHR));
            else 
                title(app.UIAxes, 'PPG Signal');
            end

            xlabel(app.UIAxes, 'Time (samples)');
            ylabel(app.UIAxes, 'Amplitude');
            legend(app.UIAxes, 'Original', 'Processed', 'Peaks', 'Location', 'best');
            grid(app.UIAxes, 'on');

            cla(app.UIAxes2);

            [pxxOrig, fOrig] = pwelch(app.currentSignal, [], [], [], app.currentFS);
            plot(app.UIAxes2, fOrig, 10*log10(pxxOrig), 'b-', 'LineWidth', 1.2);
            hold(app.UIAxes2, 'on');

            if ~isempty(app.currentProcessed)
                [pxxProc, fProc] = pwelch(app.currentProcessed, [], [], [], app.currentFS);
                plot(app.UIAxes2, fProc, 10*log10(pxxProc), 'r-', 'LineWidth', 1.2);
            end

            hold(app.UIAxes2, 'off');
            xlim(app.UIAxes2, [0, 10]);
            title(app.UIAxes2, sprintf('Frequency Spectrum | SNR Improvement: +%.1f dB', app.currentSNR));
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Magnitude (dB)');
            legend(app.UIAxes2, 'Original', 'Processed', 'Location', 'best');
            grid(app.UIAxes2, 'on');
        end

        % Button pushed function: ImportButton
        function ImportFile(app, event)
            app.StatusTextArea_2.Value = 'Status: Loading dataset from data/raw ...'; 
            drawnow;

            rawDir = fullfile(pwd, 'data', 'raw');

            if ~exist(rawDir, 'dir')
                app.StatusTextArea_2.Value = 'Status: ERROR - data/raw folder not found...';
                uiwait(warndlg('Folder "data/raw" not found. Please create it and add PPG-DaLiA files.', 'Folder Error'));
                return;
            end

            matFiles = dir(fullfile(rawDir, '*.mat'));

            if isempty(matFiles)
                app.StatusTextArea_2.Value = 'Status: ERROR - No .mat files found in data/raw folder ...';
                uiwait(warndlg('No .mat files found in "data/raw" folder.', 'File Error'));
                return;
            end

            app.datasetFiles = {matFiles.name};

            app.SelectListBox.Items = app.datasetFiles;
            app.SelectListBox.Value = app.datasetFiles{1};
        
            app.StatusTextArea_2.Value = sprintf(['Status: Found %d files in data/raw.' ...
                'Select and click PROCESS button'], length(matFiles));
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if isempty(app.datasetFiles)
                app.StatusTextArea_2.Value = 'Status: Click IMPORT button first.';
                uiwait(warndlg('Click IMPORT button first.', 'No data'));
                return;
            end

            selectedFile = app.SelectListBox.Value;

            if isempty(selectedFile) 
                app.StatusTextArea_2.Value = 'Status: No file selected.';
                uiwait(warndlg('Select a file from the list.', 'No Selection'));
                return;
            end

            app.StatusTextArea_2.Value = sprintf('Status: Processing %s ...', selectedFile);
            drawnow;

            rawDir = fullfile(pwd, 'data', 'raw');
            data = load(fullfile(rawDir, selectedFile));

            ppgSignal = [];

            if isfield(data, 'data') && isstruct(data.data)
                allPPG = [];
                for i = 1:length(data.data)
                    if isfield(data.data(i), 'ppg') && isstruct(data.data(i).ppg) && isfield(data.data(i).ppg, 'v')
                        allPPG = [allPPG; data.data(i).ppg.v(:)];
                    end
                end
                if ~isempty(allPPG)
                    ppgSignal = double(allPPG);
                end
            end

            if isempty(ppgSignal) && isfield(data, 'data') && isstruct(data.data)
                for i = 1:length(data.data)
                    if isfield(data.data(i), 'ecg') && isstruct(data.data(i).ecg) && isfield(data.data(i).ecg, 'v')
                        ppgSignal = [ppgSignal; data.data(i).ecg.v(:)];
                    end
                end
                if ~isempty(ppgSignal)
                    app.StatusTextArea_2.Value = 'Status: PPG not found, using ECG signal.';
                end
            end

            if isempty(ppgSignal)
                app.StatusTextArea_2.Value = 'Status: ERROR - PPG signal not extracted.';
                uiwait(warndlg('PPG signal not extracted from file.', 'Processing Error'));
                return;
            end

            % Normalize signal
            ppgSignal = ppgSignal - mean(ppgSignal);
            if max(abs(ppgSignal)) > 0
                ppgSignal = ppgSignal / max(abs(ppgSignal));
            end

            maxSamples = round(app.currentFS * 30);
            if length(ppgSignal) > maxSamples
                ppgSignal = ppgSignal(1:maxSamples);
            end

            app.currentSignal = ppgSignal;

            % Call optimized PPG algorithm
            try
                results = optimized_ppg_algorithm(ppgSignal, app.currentFS, 'visualize', false);
                app.currentProcessed = results.cleaned_signal;
                app.currentPeaks = results.peaks;
                app.currentHR = results.heart_rate;
                app.currentSNR = results.snr_after - results.snr_before; 
                app.currentTime = results.processing_time * 1000;

                if ~isnan(app.currentHR)
                    app.HRTextArea.Value = sprintf('Heart Rate: %.1f BPM', app.currentHR);
                else
                    app.HRTextArea.Value = 'Heart Rate: -- BPM';
                end

                app.SNRTextArea.Value = sprintf('SNR Improvement: +%.1f dB', app.currentSNR);
                app.PeaksTextArea.Value = sprintf('Detected Peaks: %d', length(app.currentPeaks));
                app.TimeTextArea.Value = sprintf('Processing Time: %.0f ms', app.currentTime);

                app.UpdatePlots();

                app.StatusTextArea_2.Value = sprintf('Status: %s processed successfully.', selectedFile);

            catch ME
                app.StatusTextArea_2.Value = sprintf('Status: ERROR - %s', ME.message);
                uiwait(warndlg(sprintf('Processing failed:\n%s', ME.message), 'Processing Error'));
            end
       
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            if isempty(app.currentSignal)
                app.StatusTextArea_2.Value = 'Status: Process a file first.';
                uiwait(warndlg('Process a file first', 'No data'));
                return;
            end

            figComp = figure('Name', 'Algorithm Comparison', ...
                'Position', [200, 200, 1000, 500], ...
                'NumberTitle', 'off');

            fs = app.currentFS;
            ppg = app.currentSignal;

            ppgDetrended = detrend(ppg, 'linear');
            [b, a] = butter(4, [0.7, 3]/(fs/2), 'bandpass');
            basicFiltered = filtfilt(b, a, double(ppgDetrended));
            basicThreshold = 0.4 * std(basicFiltered);
            [~, basicPeaks] = findpeaks(basicFiltered, 'MinPeakHeight', basicThreshold, ...
                'MinPeakDistance', round(fs*0.4));

            subplot(2,1,1);
            plot(basicFiltered, 'b-', 'LineWidth', 0.7);
            hold on;
            plot(basicPeaks, basicFiltered(basicPeaks), 'ro', 'MarkerSize', 6);
            title(sprintf('Basic Algorithm | Detected Peaks: %d', length(basicPeaks)));
            xlabel('Time (samples)');
            ylabel('Amplitude');
            legend('Filtered Signal', 'Peaks');
            grid on;

            subplot(2,1,2);
            plot(app.currentProcessed, 'r-', 'LineWidth', 1);
            hold on;
            plot(app.currentPeaks, app.currentProcessed(app.currentPeaks), ...
                'go', 'MarkerSize', 6, 'MarkerFaceColor', 'g');
            title(sprintf('Optimized Algorithm | HR = %.1f BPM, SNR +%.1f dB', ...
                app.currentHR, app.currentSNR));
            xlabel('Time (samples)');
            ylabel('Amplitude');
            legend('Processed Signal', 'Peaks');
            grid on;

            sgtitle('Algorithm Comparison: Basic vs Optimized');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {582, 582};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {474, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImportButton = uibutton(app.LeftPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFile, true);
            app.ImportButton.Text = 'Import';
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.CompareButton = uibutton(app.LeftPanel, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.StatusTextArea_2Label = uilabel(app.LeftPanel);
            app.StatusTextArea_2Label.Text = 'Status';
            app.StatusTextArea_2 = uitextarea(app.LeftPanel);
            app.SelectListBoxLabel = uilabel(app.LeftPanel);
            app.SelectListBoxLabel.Text = 'Select ';
            app.SelectListBox = uilistbox(app.LeftPanel);
            app.SelectListBox.Items = {'', '', ''};
            app.SelectListBox.Value = '';
            app.HRLabel = uilabel(app.LeftPanel);
            app.HRLabel.Text = 'HR';
            app.HRTextArea = uitextarea(app.LeftPanel);
            app.SNRLabel = uilabel(app.LeftPanel);
            app.SNRLabel.Text = 'SNR';
            app.SNRTextArea = uitextarea(app.LeftPanel);
            app.PeaksLabel = uilabel(app.LeftPanel);
            app.PeaksLabel.Text = 'Peaks';
            app.PeaksTextArea = uitextarea(app.LeftPanel);
            app.TimeLabel = uilabel(app.LeftPanel);
            app.TimeLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Basic Algorithm')
            xlabel(app.UIAxes, 'Time (samples)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Optimized Algorithm')
            xlabel(app.UIAxes2, 'Time (samples)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heart_Rate_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @UpdatePlots)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end